classdef MosynGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Program                       matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenProjectMenu               matlab.ui.container.Menu
        LoadNodeLocationsMenu         matlab.ui.container.Menu
        CalculateThresholdMenu        matlab.ui.container.Menu
        ConfigureMenu                 matlab.ui.container.Menu
        CalculateMeasuresMenu         matlab.ui.container.Menu
        RemoveTimeColumnCheckBox      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        DirectoryPanel                matlab.ui.container.Panel
        DirectoryEditField            matlab.ui.control.EditField
        Open                          matlab.ui.control.Button
        NodeLocationPanel             matlab.ui.container.Panel
        NodeLocationEditField         matlab.ui.control.EditField
        FilesPanel                    matlab.ui.container.Panel
        FilesTable                    matlab.ui.control.Table
        BandPassFilterPanel           matlab.ui.container.Panel
        AllowBandPassCheckBox         matlab.ui.control.CheckBox
        InitialFrequencyEditField     matlab.ui.control.NumericEditField
        FinalFrequencyEditField       matlab.ui.control.NumericEditField
        AcqRateEditField              matlab.ui.control.NumericEditField
        AcqRateEditFieldLabel         matlab.ui.control.Label
        FinalValueEditFieldLabel      matlab.ui.control.Label
        InitialValueEditFieldLabel    matlab.ui.control.Label
        TimeandFrequencyPanel         matlab.ui.container.Panel
        InitialTimeEditField          matlab.ui.control.NumericEditField
        FinalTimeEditField            matlab.ui.control.NumericEditField
        FinalTimeEditFieldLabel       matlab.ui.control.Label
        RealFinalTimeCheckBox         matlab.ui.control.CheckBox
        InitialTimeEditFieldLabel     matlab.ui.control.Label
        TSCPanel                      matlab.ui.container.Panel
        TSCLimitEditField             matlab.ui.control.NumericEditField
        TSCLimitEditFieldLabel        matlab.ui.control.Label
        MotifSynchronizationPropertiesPanel  matlab.ui.container.Panel
        WindowLengthEditField         matlab.ui.control.NumericEditField
        MotifLagEditField_2Label      matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.NumericEditField
        LagWindowEditField            matlab.ui.control.NumericEditField
        TaoMinEditField               matlab.ui.control.NumericEditField
        TaoMaxEditField               matlab.ui.control.NumericEditField
        TaoMaxEditFieldLabel          matlab.ui.control.Label
        TaoMinEditFieldLabel          matlab.ui.control.Label
        LagWindowEditField_3Label     matlab.ui.control.Label
        MotifLagEditField             matlab.ui.control.NumericEditField
        ThresholdEditField_2Label     matlab.ui.control.Label
        WindowLengthEditField_2Label  matlab.ui.control.Label
        MeasuresPanel                 matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        GenerateTVGButton             matlab.ui.control.Button
    end


    properties (Access = private)
        Files
        Path
        Location

        MeasureConfigurationButton

    end

    methods (Access = private)

        function configureFields(app)

            filename = strcat(app.Path, app.Files{1});

            [~,~,ext] = fileparts(filename);

            if (ext == ".mat")
                app.loadConfigurationFromMatFile(filename)
            else
                app.getSavedValues
            end

        end

        function loadConfigurationFromMatFile (app, filename)

            load (filename, 'initialTime', 'finalTime', 'realTime', 'initialFrequency', 'finalFrequency', 'hasFrequency', 'removeTimeColumn', 'acqRate', 'slidWindow', 'taoMin', 'taoMax', 'threshold', 'numNodes' )

            app.RemoveTimeColumnCheckBox.Checked = removeTimeColumn;
            app.AllowBandPassCheckBox.Enable = 'off';

            app.AcqRateEditField.Value = acqRate;
            app.AcqRateEditField.Enable = 'off';

            app.AllowBandPassCheckBox.Value = hasFrequency;
            app.AllowBandPassCheckBox.Enable = 'off';

            app.InitialFrequencyEditField.Value = initialFrequency;
            app.InitialFrequencyEditField.Enable = 'off';

            app.FinalFrequencyEditField.Value = finalFrequency;
            app.FinalFrequencyEditField.Enable = 'off';

            app.InitialTimeEditField.Value = initialTime;
            app.InitialTimeEditField.Enable = 'off';

            app.FinalTimeEditField.Value = finalTime;
            app.FinalTimeEditField.Enable = 'off';

            app.RealFinalTimeCheckBox.Value = realTime;
            app.RealFinalTimeCheckBox.Enable = 'off';

            app.WindowLengthEditField.Value = slidWindow;
            app.WindowLengthEditField.Enable = 'off';

            app.ThresholdEditField.Value = threshold;
            app.ThresholdEditField.Enable = 'off';

            app.TaoMinEditField.Value = taoMin;
            app.TaoMinEditField.Enable = 'off';

            app.TaoMaxEditField.Value = taoMax;
            app.TaoMaxEditField.Enable = 'off';

        end

        function getSavedValues(app)

            app.RemoveTimeColumnCheckBox.Enable = 'on';
            app.InitialTimeEditField.Enable = 'on';
            app.FinalTimeEditField.Enable = 'on';
            app.RealFinalTimeCheckBox.Enable = 'on';
            app.WindowLengthEditField.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.TaoMinEditField.Enable = 'on';
            app.TaoMaxEditField.Enable = 'on';

            if (~isempty(app.Files) && ~isstring(app.Files))
                nodes = cellfun(@getNumberOfNodes, strcat(app.Path,'\', app.Files), 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];

            end

            if(~isempty(getenv('Motif_AcqRate')))
                app.AcqRateEditField.Value = str2num(getenv('Motif_AcqRate'));
            end

            if(~isempty(getenv('Motif_InitialTime')))
                app.InitialTimeEditField.Value = str2num(getenv('Motif_InitialTime'));
            end

            if(~isempty(getenv('Motif_FinalTime')))
                app.FinalTimeEditField.Value = str2num(getenv('Motif_FinalTime'));
            end

            if(~isempty(getenv('Motif_WindowLength')))
                app.WindowLengthEditField.Value = str2num(getenv('Motif_WindowLength'));
            end

            if(~isempty(getenv('Motif_Threshold')))
                app.ThresholdEditField.Value = str2num(getenv('Motif_Threshold'));
            end

            if(~isempty(getenv('Motif_TaoMin')))
                app.TaoMinEditField.Value = str2num(getenv('Motif_TaoMin'));
            end

            if(~isempty(getenv('Motif_TaoMax')))
                app.TaoMaxEditField.Value = str2num(getenv('Motif_TaoMax'));
            end

            if(~isempty(getenv('Motif_MotifLagValue')))
                app.MotifLagEditField.Value = str2num(getenv('Motif_MotifLagValue'));
            end

            if(~isempty(getenv('Motif_LagWindow')))
                app.LagWindowEditField.Value = str2num(getenv('Motif_LagWindow'));
            end

            if(~isempty(getenv('Motif_TSCLim')))
                app.TSCLimitEditField.Value = str2num(getenv('Motif_TSCLim'));
            end
        end
        
        function configureMeasureTable(app)

            measureRegistry = MeasureRegistry.getInstance();

            app.UITable.Data = measureRegistry.getMeasuresProperties({'name', 'active'});
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

            global globalFiles globalPath;

            if (isempty(globalPath) || isnumeric(globalPath))
                globalPath = "";
            end

            if (isempty(globalFiles) || isnumeric(globalFiles))
                globalFiles = "";
            end

            app.Files = globalFiles;
            app.Path = globalPath;
            app.DirectoryEditField.Value = app.Path;


            if (~isempty(app.Files) && ~isstring(app.Files))
                nodes = cellfun(@getNumberOfNodes, strcat(app.Path,'\', app.Files), 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];

                app.GenerateTVGButton.Enable = 'on';
            end

            app.configureMeasureTable

            app.configureFields
        end

        % Menu selected function: OpenProjectMenu
        function OpenProjectMenuSelected(app, event)
            [app.Files,app.Path,indx] = uigetfile('*.mat');

            if indx > 0
                mat = load(strcat(app.Path,app.Files));
                project = mat.project;
                UISync(project);
            end

        end

        % Button pushed function: GenerateTVGButton
        function GenerateTVGButtonPushed(app, event)

            initialTime = app.InitialTimeEditField.Value;
            finalTime = app.FinalTimeEditField.Value;
            slidWindow = app.WindowLengthEditField.Value;
            taoMin  = app.TaoMinEditField.Value;
            taoMax  = app.TaoMaxEditField.Value;
            threshold = app.ThresholdEditField.Value;
            motifLag = app.LagWindowEditField.Value;
            lagWindow = app.LagWindowEditField.Value;
            realTime = app.RealFinalTimeCheckBox.Value;
            initialFrequency= app.InitialFrequencyEditField.Value;
            finalFrequency = app.FinalFrequencyEditField.Value;
            hasFrequency = app.AllowBandPassCheckBox.Value;
            timeColumn = app.RemoveTimeColumnCheckBox.Checked;
            AcqRate = app.AcqRateEditField.Value;
            tscLim = app.TSCLimitEditField.Value;

            setenv('Motif_AcqRate', num2str(AcqRate));
            setenv('Motif_InitialTime', num2str(initialTime));
            setenv('Motif_FinalTime', num2str(finalTime));
            setenv('Motif_WindowLength', num2str(slidWindow));
            setenv('Motif_TaoMin', num2str(taoMin));
            setenv('Motif_TaoMax', num2str(taoMax));
            setenv('Motif_Threshold', num2str(threshold));
            setenv('Motif_MotifLagValue', num2str(motifLag));
            setenv('Motif_LagWindow', num2str(lagWindow));
            setenv('Motif_TSCLim', num2str(tscLim));

            try
                app.GenerateTVGButton.Enable = 'off';

                configuration = Configuration(app.Path, app.Files, app.Location, initialTime, finalTime, realTime, initialFrequency, finalFrequency, timeColumn, AcqRate, slidWindow, taoMin, taoMax, threshold, tscLim);
    
                project = Project(configuration);
    
                project.run;
    
            catch error
                app.GenerateTVGButton.Enable = 'on';
                multiWaitbar('CloseAll');
                rethrow(error)
            end

            app.GenerateTVGButton.Enable = 'on';

            if (project ~= -1)
                UISync(project);
            end
        end

        % Button pushed function: Open
        function OpenButtonPushed(app, event)
            global globalFiles globalPath

            [files, path] = uigetfile({'*.asc;*.csv', 'Data Files (*.asc;*.csv)'; '*.tvg.mat', 'TVG Files (tvg.mat)'}, 'Select one or more files',  'MultiSelect', 'on', globalPath);
            
            if (~iscell(files))
                if (files == 0)
                    return;
                end

                app.Files = cellstr(files);
                app.Path = cellstr(path);
            else
                app.Files = files;
                app.Path = path;
            end


            globalFiles = app.Files;
            globalPath = app.Path;

            app.configureFields

            if (~isempty(app.Files) && ~isstring(app.Files))
                nodes = cellfun(@getNumberOfNodes, strcat(app.Path,'\', app.Files), 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];

                app.GenerateTVGButton.Enable = 'on';
            end


            app.DirectoryEditField.Value = app.Path;

        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            value = app.DirectoryEditField.Value;
            app.Path = value;
            dirData = dir([value '*.asc']);
            dirIndex = [dirData.isdir];
            app.Files = {dirData(~dirIndex).name}';
            
            if (~isempty(app.Files) && ~isstring(app.Files))
                nodes = cellfun(@getNumberOfNodes, strcat(app.Path,'\', app.Files), 'UniformOutput', false);
                app.FilesTable.Data = [app.Files' nodes'];

                app.GenerateTVGButton.Enable = 'on';
            end
        end

        % Value changed function: RealFinalTimeCheckBox
        function RealFinalTimeCheckBoxValueChanged(app, event)

            if(app.RealFinalTimeCheckBox.Value == 1)
                app.FinalTimeEditField.Enable = false;
            else
                app.FinalTimeEditField.Enable = true;
            end

        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox({'MoSyn - Motif Synchronization and Complex Network Analysis 4.0';'Developed by Neuro Redes (UFBA)';'Author: Marcos Netto';'Mail: mpnetto88@gmail.com'});
        end

        % Menu selected function: LoadNodeLocationsMenu
        function LoadNodeLocationsMenuSelected(app, event)
            [file,path,indx] = uigetfile('*.txt');
            app.Program.Visible = 'off';
            app.Program.Visible = 'on';
            if indx > 0
                app.Location = strcat(path,file);
                app.NodeLocationEditField.Value = app.Location;
            end
        end

        % Value changed function: AllowBandPassCheckBox
        function AllowBandPassCheckBoxValueChanged(app, event)

            if (app.AllowBandPassCheckBox.Value == 1)
                app.InitialFrequencyEditField.Enable = 'on';
                app.FinalFrequencyEditField.Enable = 'on';
                app.AcqRateEditField.Enable = 'on';
                 
            else
                app.InitialFrequencyEditField.Enable = 'off';
                app.FinalFrequencyEditField.Enable = 'off';
                app.AcqRateEditField.Enable = 'off';
            end
        end

        % Menu selected function: CalculateThresholdMenu
        function CalculateThresholdMenuSelected(app, event)
            slidWindow = app.WindowLengthEditField.Value;
            taoMin  = app.TaoMinEditField.Value;
            taoMax  = app.TaoMaxEditField.Value;
            motifLag = app.LagWindowEditField.Value;
            lagWindow = app.LagWindowEditField.Value;

            if (~isempty(app.Files) && ~isstring(app.Files))
                thresholdApp = CalcThreshold(app.Path, app.Files(1), slidWindow, lagWindow, taoMin,taoMax, motifLag);
                waitfor(thresholdApp);
                display(thresholdApp);
                %app.ThresholdEditField.Value = thresholdApp.threshold;
            else
                uialert(app.Program,'You have to load at least one file','No File');
            end

        end

        % Menu selected function: CalculateMeasuresMenu
        function CalculateIndexesClicked(app, event)

            if app.CalculateMeasuresMenu.Checked == 'on'
                app.CalculateMeasuresMenu.Checked = 'off';
            else
                app.CalculateMeasuresMenu.Checked = 'on';
            end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
             % Get the row index of the selected cell
            row = event.Indices(1);

            measureRegistry = MeasureRegistry.getInstance();

            measures = measureRegistry.getMeasuresProperties({'name'});

            name = measures{row};
            
            % Create a context menu
            menu = uicontextmenu(app.MeasuresPanel.Parent);

            uimenu(menu, 'Label', 'Configuration', 'Callback', @(~,~) disp(['Option 1 selected ' name ]));
        
            % Associate the context menu with the selected row
            app.UITable.ContextMenu = menu;
           
        end

        % Menu selected function: RemoveTimeColumnCheckBox
        function RemoveTimeColumnCheckBoxMenuSelected(app, event)
             if app.RemoveTimeColumnCheckBox.Checked == 'on'
                app.RemoveTimeColumnCheckBox.Checked = 'off';
            else
                app.RemoveTimeColumnCheckBox.Checked = 'on';
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            indice = event.Indices(1);
            active = event.NewData;
            
            measureRegistry = MeasureRegistry.getInstance();
            
            measures = measureRegistry.getMeasures;
            
            measures(indice).active = active;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Program = uifigure('Visible', 'off');
            app.Program.Name = 'MoSyn - Motif Synchronization';
            app.FileMenu = uimenu(app.Program);
            app.FileMenu.Text = 'File';
            app.OpenProjectMenu = uimenu(app.FileMenu);
            app.OpenProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProjectMenuSelected, true);
            app.OpenProjectMenu.Separator = 'on';
            app.OpenProjectMenu.Accelerator = 'p';
            app.OpenProjectMenu.Text = 'Open Project';
            app.LoadNodeLocationsMenu = uimenu(app.FileMenu);
            app.LoadNodeLocationsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNodeLocationsMenuSelected, true);
            app.LoadNodeLocationsMenu.Separator = 'on';
            app.LoadNodeLocationsMenu.Accelerator = 'L';
            app.LoadNodeLocationsMenu.Text = 'Load Node Locations';
            app.CalculateThresholdMenu = uimenu(app.FileMenu);
            app.CalculateThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateThresholdMenuSelected, true);
            app.CalculateThresholdMenu.Accelerator = 'T';
            app.CalculateThresholdMenu.Text = 'Calculate Threshold';
            app.ConfigureMenu = uimenu(app.Program);
            app.ConfigureMenu.Text = 'Configure';
            app.CalculateMeasuresMenu = uimenu(app.ConfigureMenu);
            app.CalculateMeasuresMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateIndexesClicked, true);
            app.CalculateMeasuresMenu.Checked = 'on';
            app.CalculateMeasuresMenu.Text = 'Calculate Measures';
            app.RemoveTimeColumnCheckBox = uimenu(app.ConfigureMenu);
            app.RemoveTimeColumnCheckBox.MenuSelectedFcn = createCallbackFcn(app, @RemoveTimeColumnCheckBoxMenuSelected, true);
            app.RemoveTimeColumnCheckBox.Text = 'Remove Time Column';
            app.AboutMenu = uimenu(app.Program);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GenerateTVGButton = uibutton(app.Program, 'push');
            app.GenerateTVGButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTVGButtonPushed, true);
            app.GenerateTVGButton.Enable = 'off';
            app.GenerateTVGButton.Text = 'Generate TVG';
            app.MeasuresPanel = uipanel(app.Program);
            app.MeasuresPanel.Title = 'Measures';
            app.UITable = uitable(app.MeasuresPanel);
            app.UITable.ColumnName = {'Measures'; ''; 'Conf.'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false];
            app.UITable.ColumnEditable = [false true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.MotifSynchronizationPropertiesPanel = uipanel(app.Program);
            app.MotifSynchronizationPropertiesPanel.Title = 'Motif Synchronization Properties';
            app.WindowLengthEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.WindowLengthEditField_2Label.Text = 'Window Length';
            app.ThresholdEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.MotifLagEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.MotifLagEditField.Limits = [0 Inf];
            app.MotifLagEditField.Value = 1;
            app.LagWindowEditField_3Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.LagWindowEditField_3Label.Text = 'Lag Window';
            app.TaoMinEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMinEditFieldLabel.Text = 'Tao Min';
            app.TaoMaxEditFieldLabel = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.TaoMaxEditFieldLabel.Text = 'Tao Max';
            app.TaoMaxEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMaxEditField.Limits = [0 Inf];
            app.TaoMaxEditField.Value = 3;
            app.TaoMinEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.TaoMinEditField.Limits = [0 Inf];
            app.LagWindowEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.LagWindowEditField.Limits = [0 Inf];
            app.LagWindowEditField.Value = 1;
            app.ThresholdEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 1];
            app.ThresholdEditField.Value = 0.9;
            app.MotifLagEditField_2Label = uilabel(app.MotifSynchronizationPropertiesPanel);
            app.MotifLagEditField_2Label.Text = 'Motif Lag';
            app.WindowLengthEditField = uieditfield(app.MotifSynchronizationPropertiesPanel, 'numeric');
            app.WindowLengthEditField.Value = 20;
            app.TSCPanel = uipanel(app.Program);
            app.TSCPanel.Title = 'TSC';
            app.TSCLimitEditFieldLabel = uilabel(app.TSCPanel);
            app.TSCLimitEditFieldLabel.Text = 'TSC Limit';
            app.TSCLimitEditField = uieditfield(app.TSCPanel, 'numeric');
            app.TSCLimitEditField.Limits = [0 1];
            app.TimeandFrequencyPanel = uipanel(app.Program);
            app.TimeandFrequencyPanel.Title = 'Time and Frequency';
            app.InitialTimeEditFieldLabel = uilabel(app.TimeandFrequencyPanel);
            app.InitialTimeEditFieldLabel.Text = 'Initial Time';
            app.RealFinalTimeCheckBox = uicheckbox(app.TimeandFrequencyPanel);
            app.RealFinalTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RealFinalTimeCheckBoxValueChanged, true);
            app.RealFinalTimeCheckBox.Text = 'Real Final Time';
            app.FinalTimeEditFieldLabel = uilabel(app.TimeandFrequencyPanel);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.TimeandFrequencyPanel, 'numeric');
            app.FinalTimeEditField.Limits = [0 Inf];
            app.FinalTimeEditField.ValueDisplayFormat = '%.0f';
            app.FinalTimeEditField.Value = 1000;
            app.InitialTimeEditField = uieditfield(app.TimeandFrequencyPanel, 'numeric');
            app.InitialTimeEditField.ValueDisplayFormat = '%.0f';
            app.InitialTimeEditField.Value = 1;
            app.BandPassFilterPanel = uipanel(app.Program);
            app.BandPassFilterPanel.Title = 'Band Pass Filter';
            app.InitialValueEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.InitialValueEditFieldLabel.Enable = 'off';
            app.InitialValueEditFieldLabel.Text = 'Initial Value';
            app.FinalValueEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.FinalValueEditFieldLabel.Enable = 'off';
            app.FinalValueEditFieldLabel.Text = 'Final Value';
            app.AcqRateEditFieldLabel = uilabel(app.BandPassFilterPanel);
            app.AcqRateEditFieldLabel.Enable = 'off';
            app.AcqRateEditFieldLabel.Text = 'Acq Rate';
            app.AcqRateEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.AcqRateEditField.Enable = 'off';
            app.FinalFrequencyEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.FinalFrequencyEditField.Limits = [0 Inf];
            app.FinalFrequencyEditField.Enable = 'off';
            app.InitialFrequencyEditField = uieditfield(app.BandPassFilterPanel, 'numeric');
            app.InitialFrequencyEditField.Limits = [0 Inf];
            app.InitialFrequencyEditField.Enable = 'off';
            app.AllowBandPassCheckBox = uicheckbox(app.BandPassFilterPanel);
            app.AllowBandPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowBandPassCheckBoxValueChanged, true);
            app.AllowBandPassCheckBox.Text = 'Allow Band Pass';
            app.FilesPanel = uipanel(app.Program);
            app.FilesPanel.Title = 'Files';
            app.FilesTable = uitable(app.FilesPanel);
            app.FilesTable.ColumnName = {'Subjects'; 'Nodes'};
            app.FilesTable.RowName = {};
            app.NodeLocationPanel = uipanel(app.Program);
            app.NodeLocationPanel.Title = 'Node Location';
            app.NodeLocationEditField = uieditfield(app.NodeLocationPanel, 'text');
            app.NodeLocationEditField.Editable = 'off';
            app.DirectoryPanel = uipanel(app.Program);
            app.DirectoryPanel.Title = 'Directory';
            app.Open = uibutton(app.DirectoryPanel, 'push');
            app.Open.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.Open.Text = '...';
            app.DirectoryEditField = uieditfield(app.DirectoryPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.Program.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosynGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Program)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Program)
        end
    end
end